classdef ENVIRT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LoadContigSpectrumButton      matlab.ui.control.Button
        PersistIntermediatesCheckBox  matlab.ui.control.CheckBox
        InputConfigurationButton      matlab.ui.control.Button
        SearchConfigurationButton     matlab.ui.control.Button
        SelectSaveLocationButton      matlab.ui.control.Button
        RUNButton                     matlab.ui.control.Button
    end


    properties (Access = private)
        cs_file_path % config spectrum file path
         n_reads
         avg_read_length
         overlap_length
         trim_length
         M_min
         M_max
         p_res
         p_max
         L_res
         L_min
         L_max
         n_windows
         result_folder
         persist_intermediates
    end


    methods (Access = private)

        % Button pushed function: SearchConfigurationButton
        function SearchConfigurationButtonPushed(app, event)
            search_config_prompt = {'M_min', 'M_max', 'p_res', 'p_max', 'L_res', 'L_min', 'L_max', 'n_windows'};
            def_search_config = {'1', '100000', '0.01', '5', '500', '500', '300500', '29' };
            
            search_config = inputdlg(search_config_prompt, "Search Configurations", [1, 35], def_search_config);
            app.M_min = search_config{1};
            app.M_max = search_config{2};
            app.p_res = str2num(search_config{3});
            app.p_max = str2num(search_config{4});
            app.L_res = str2num(search_config{5});
            app.L_min = str2num(search_config{6});
            app.L_max = str2num(search_config{7});
            app.n_windows = str2num(search_config{8});
            set(app.SearchConfigurationButton,'Enable', 'off');
            set(app.SelectSaveLocationButton, 'Enable', 'on');
        end

        % Button pushed function: LoadContigSpectrumButton
        function LoadContigSpectrumButtonPushed(app, event)
            [cs_file , cs_path] = uigetfile('*.*','Select Contig Spectrum File');
            app.cs_file_path = fullfile(cs_path, cs_file);
            app.InputConfigurationButton.Enable();
            set(app.InputConfigurationButton, 'Enable', 'on');
            set(app.LoadContigSpectrumButton, 'Enable', 'off');
        end

        % Button pushed function: InputConfigurationButton
        function InputConfigurationButtonPushed(app, event)
            contig_config_prompt = {"No. of reads", "Average Read Length", "Overlap Length", "Trim Length"};
            def_contig_config = {'', '','',''};
            contig_config = inputdlg(contig_config_prompt,"Contig Spectrum Configurations", [1, 35], def_contig_config);
            app.n_reads = str2num(contig_config{1}); app.avg_read_length = str2num(contig_config{2}); 
            app.overlap_length = str2num(contig_config{3}); app.trim_length = str2num(contig_config{4});
            set(app.SearchConfigurationButton, 'Enable', 'on');
            set(app.InputConfigurationButton, 'Enable', 'off');
        end

        % Button pushed function: SelectSaveLocationButton
        function SelectSaveLocationButtonPushed(app, event)
            save_loc = uigetdir('./', 'Select Result Save Location');
            app.result_folder = save_loc;
            set(app.RUNButton, 'Enable', 'on');
            set(app.PersistIntermediatesCheckBox, 'Enable', 'on');
            set(app.SelectSaveLocationButton, 'Enable', 'off');
        end

        % Value changed function: PersistIntermediatesCheckBox
        function PersistIntermediatesCheckBoxValueChanged(app, event)
            value = app.PersistIntermediatesCheckBox.Value;
            app.persist_intermediates = value;

        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            addpath('lib');
            set(app.PersistIntermediatesCheckBox, 'Enable', 'off');
            ENVIRT_SEARCH(app.cs_file_path, app.n_reads, app.avg_read_length, app.overlap_length, app.trim_length, app.M_min, app.M_max, app.p_res, app.p_max, app.L_res, app.L_min, app.L_max, app.n_windows, app.result_folder, app.persist_intermediates);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadContigSpectrumButton = uibutton(app.UIFigure, 'push');
            app.LoadContigSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @LoadContigSpectrumButtonPushed, true);
            app.LoadContigSpectrumButton.Text = 'Load Contig Spectrum';
            app.PersistIntermediatesCheckBox = uicheckbox(app.UIFigure);
            app.PersistIntermediatesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PersistIntermediatesCheckBoxValueChanged, true);
            app.PersistIntermediatesCheckBox.Enable = 'off';
            app.PersistIntermediatesCheckBox.Text = 'Persist Intermediates';
            app.InputConfigurationButton = uibutton(app.UIFigure, 'push');
            app.InputConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @InputConfigurationButtonPushed, true);
            app.InputConfigurationButton.Enable = 'off';
            app.InputConfigurationButton.Text = 'Input Configuration';
            app.SearchConfigurationButton = uibutton(app.UIFigure, 'push');
            app.SearchConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @SearchConfigurationButtonPushed, true);
            app.SearchConfigurationButton.Enable = 'off';
            app.SearchConfigurationButton.Text = 'Search Configuration';
            app.SelectSaveLocationButton = uibutton(app.UIFigure, 'push');
            app.SelectSaveLocationButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSaveLocationButtonPushed, true);
            app.SelectSaveLocationButton.Enable = 'off';
            app.SelectSaveLocationButton.Text = 'Select Save Location';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Enable = 'off';
            app.RUNButton.Text = 'RUN';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ENVIRT

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end